classdef trackmate_analysis_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        TrackmateanalysisTextAreaLabel  matlab.ui.control.Label
        TrackmateanalysisTextArea       matlab.ui.control.TextArea
        referencetrackmatefileButton    matlab.ui.control.Button
        showrefxml                      matlab.ui.control.EditField
        othertrackmatefilesButton       matlab.ui.control.Button
        showxml_1                       matlab.ui.control.EditField
        outputfolderEditFieldLabel      matlab.ui.control.Label
        outputfolder                    matlab.ui.control.EditField
        showxml_2                       matlab.ui.control.EditField
        showxml_3                       matlab.ui.control.EditField
        showxml_5                       matlab.ui.control.EditField
        showxml_6                       matlab.ui.control.EditField
        showxml_7                       matlab.ui.control.EditField
        showxml_8                       matlab.ui.control.EditField
        maxdistanceEditFieldLabel       matlab.ui.control.Label
        maxdistanceEditField            matlab.ui.control.NumericEditField
        nBinsEditFieldLabel             matlab.ui.control.Label
        nBinsEditField                  matlab.ui.control.NumericEditField
        assocTimeEditFieldLabel         matlab.ui.control.Label
        assocTimeEditField              matlab.ui.control.NumericEditField
        StartButton                     matlab.ui.control.Button
        mintracksdwelltimeEditFieldLabel  matlab.ui.control.Label
        mintracksdwelltimeEditField     matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        refxml = '';
        xml = {};
        labels = {};
    end
    
    methods (Access = private)
        
        function startanalysis(app)
            set(app.StartButton,'Enable', 'off');
            app.StartButton.FontColor = [0.5 0.5 0.5];        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: referencetrackmatefileButton
        function referencetrackmatefileButtonPushed(app, event)
            [app.showrefxml.Value,refpath] = uigetfile('*.xml','Select trackmate file ');
            app.refxml = strcat(refpath, app.showrefxml.Value);
            figure(app.UIFigure);
            app.StartButton.Enable='On';
            app.StartButton.FontColor = [0 0 0];
        end

        % Button pushed function: othertrackmatefilesButton
        function othertrackmatefilesButtonPushed(app, event)
            [app.xml,path] = uigetfile('*.xml','Select one or more trackmate Files ','MultiSelect','on');
            if isa(app.xml,'char') == true
                app.xml = cellstr(app.xml);
                app.showxml_1.Value = char(app.xml(1));
                app.showxml_2.Value = '';
                app.showxml_3.Value = '';
            elseif length(app.xml) ==2 
                app.showxml_1.Value = char(app.xml(1));
                app.showxml_2.Value = char(app.xml(2));
                app.showxml_3.Value = '';
            elseif length(app.xml) ==3 
                app.showxml_1.Value = char(app.xml(1));
                app.showxml_2.Value = char(app.xml(2));
                app.showxml_3.Value = char(app.xml(3));
            else 
                app.showxml_1.Value = '';
                app.showxml_2.Value = '';
                app.showxml_3.Value = '';
            end
            app.xml = strcat(path, app.xml);
            figure(app.UIFigure);
            app.StartButton.Enable='On';
            app.StartButton.FontColor = [0 0 0];
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            try
                set(app.StartButton,'Enable', 'off');
                app.StartButton.FontColor = [0.5 0.5 0.5];
                pause(5);
                if length(app.xml) == 1
                    app.labels = {app.showxml_5.Value, app.showxml_6.Value};
                elseif length(app.xml) == 2
                    app.labels = {app.showxml_5.Value, app.showxml_6.Value, app.showxml_7.Value};
                elseif length(app.xml) == 3
                    app.labels = {app.showxml_5.Value, app.showxml_6.Value, app.showxml_7.Value, app.showxml_8.Value};
                end
                if trackmateTrackAnalysis(app.refxml, app.xml, app.outputfolder.Value, app.labels, app.maxdistanceEditField.Value, app.assocTimeEditField.Value, app.mintracksdwelltimeEditField.Value, app.nBinsEditField.Value)
                    set(app.StartButton,'Enable', 'on');
                    app.StartButton.FontColor = [0 0 0];
                end
            catch
                set(app.StartButton,'Enable', 'on');
                app.StartButton.FontColor = [0 0 0];  
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.TrackmateanalysisTextAreaLabel = uilabel(app.Tab);
            app.TrackmateanalysisTextAreaLabel.Text = 'Trackmate analysis';
            app.TrackmateanalysisTextArea = uitextarea(app.Tab);
            app.TrackmateanalysisTextArea.Value = {'Prototype of Trackmate analysis for Yevin'};
            app.referencetrackmatefileButton = uibutton(app.Tab, 'push');
            app.referencetrackmatefileButton.ButtonPushedFcn = createCallbackFcn(app, @referencetrackmatefileButtonPushed, true);
            app.referencetrackmatefileButton.Text = 'reference trackmate file';
            app.showrefxml = uieditfield(app.Tab, 'text');
            app.showrefxml.Editable = 'off';
            app.othertrackmatefilesButton = uibutton(app.Tab, 'push');
            app.othertrackmatefilesButton.ButtonPushedFcn = createCallbackFcn(app, @othertrackmatefilesButtonPushed, true);
            app.othertrackmatefilesButton.Text = 'other trackmate files';
            app.showxml_1 = uieditfield(app.Tab, 'text');
            app.showxml_1.Editable = 'off';
            app.outputfolderEditFieldLabel = uilabel(app.Tab);
            app.outputfolderEditFieldLabel.Text = 'output folder';
            app.outputfolder = uieditfield(app.Tab, 'text');
            app.outputfolder.Value = 'Analysis results';
            app.showxml_2 = uieditfield(app.Tab, 'text');
            app.showxml_2.Editable = 'off';
            app.showxml_3 = uieditfield(app.Tab, 'text');
            app.showxml_3.Editable = 'off';
            app.showxml_5 = uieditfield(app.Tab, 'text');
            app.showxml_5.Value = 'Ligand';
            app.showxml_6 = uieditfield(app.Tab, 'text');
            app.showxml_6.Value = 'Mek';
            app.showxml_7 = uieditfield(app.Tab, 'text');
            app.showxml_8 = uieditfield(app.Tab, 'text');
            app.maxdistanceEditFieldLabel = uilabel(app.Tab);
            app.maxdistanceEditFieldLabel.Text = 'max distance';
            app.maxdistanceEditField = uieditfield(app.Tab, 'numeric');
            app.maxdistanceEditField.Value = 0.2;
            app.nBinsEditFieldLabel = uilabel(app.Tab);
            app.nBinsEditFieldLabel.Text = 'nBins';
            app.nBinsEditField = uieditfield(app.Tab, 'numeric');
            app.nBinsEditField.ValueDisplayFormat = '%.0f';
            app.nBinsEditField.Value = 70;
            app.assocTimeEditFieldLabel = uilabel(app.Tab);
            app.assocTimeEditFieldLabel.Text = 'assoc Time';
            app.assocTimeEditField = uieditfield(app.Tab, 'numeric');
            app.assocTimeEditField.ValueDisplayFormat = '%.0f';
            app.assocTimeEditField.Value = 3;
            app.StartButton = uibutton(app.Tab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.mintracksdwelltimeEditFieldLabel = uilabel(app.Tab);
            app.mintracksdwelltimeEditFieldLabel.Text = 'min tracks dwelltime';
            app.mintracksdwelltimeEditField = uieditfield(app.Tab, 'numeric');
            app.mintracksdwelltimeEditField.ValueDisplayFormat = '%.0f';
            app.mintracksdwelltimeEditField.Value = 2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trackmate_analysis_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end